classdef ShittyAPP2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        InputsTab                     matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        PRNEditFieldLabel             matlab.ui.control.Label
        PRNEditField                  matlab.ui.control.NumericEditField
        SamplingFrequencyHzEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencyHzEditField  matlab.ui.control.NumericEditField
        IntermediateFrequencyHzEditFieldLabel  matlab.ui.control.Label
        IntermediateFrequencyHzEditField  matlab.ui.control.NumericEditField
        msectoprocessEditFieldLabel   matlab.ui.control.Label
        msectoprocessEditField        matlab.ui.control.NumericEditField
        msectoAverageEditFieldLabel   matlab.ui.control.Label
        msectoAverageEditField        matlab.ui.control.NumericEditField
        ProcessDataButton             matlab.ui.control.Button
        RunAnimationButton            matlab.ui.control.Button
        AmplitudeDataTab              matlab.ui.container.Tab
        GridLayout4                   matlab.ui.container.GridLayout
        Correlation_results           matlab.ui.control.UIAxes
        Prompt_I                      matlab.ui.control.UIAxes
        Prompt_Q                      matlab.ui.control.UIAxes
        FrequencyDataTab              matlab.ui.container.Tab
        GridLayout3                   matlab.ui.container.GridLayout
        Tracked_Code_Freq             matlab.ui.control.UIAxes
        Tracked_Int_Freq              matlab.ui.control.UIAxes
        AnimationTab                  matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        direct_I                      matlab.ui.control.UIAxes
        direct_Q                      matlab.ui.control.UIAxes
        reflect_I                     matlab.ui.control.UIAxes
        reflect_Q                     matlab.ui.control.UIAxes
        Reflect_Direct_Ratio          matlab.ui.control.UIAxes
        Reflect_Composite             matlab.ui.control.UIAxes
    end

    
    %Not sure why i have to do this
    properties (Access = private)
        e_i
        e_q
        p_i
        p_q
        l_i
        l_q
        carrierfq
        codefq
        msec_avg
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ProcessDataButton
        function ProcessDataButtonPushed(app, event)
            % Change both buttons to red
            app.ProcessDataButton.BackgroundColor = [1 0 0];
            app.RunAnimationButton.BackgroundColor = [1 0 0];
            % Pause ?
            pause(0.5)
            % Take input data and set to variables
            PRN = app.PRNEditField.Value;
            Fs = app.SamplingFrequencyHzEditField.Value;
            IntFreq = app.IntermediateFrequencyHzEditField.Value;
            msec_to_process = app.msectoprocessEditField.Value;
            disp(PRN)
            % Process Data with findandtrack.m
            [app.e_i,app.e_q,app.p_i,app.p_q,app.l_i,app.l_q,app.carrierfq,app.codefq] = findandtrack(PRN, msec_to_process, Fs, IntFreq);
            % Change both buttons to green
            app.ProcessDataButton.BackgroundColor = [0 1 0];
            app.RunAnimationButton.BackgroundColor = [0 1 0];
            
            % Plot with plotresults.m
            plotresults(app.e_i,app.e_q,app.p_i,app.p_q,app.l_i,app.l_q,app.carrierfq,app.codefq,app.Tracked_Code_Freq,app.Tracked_Int_Freq, ...
                   app.Correlation_results, app.Prompt_I, app.Prompt_Q)
            %Pause to allow app to catch up
            pause(0.5) 
        end

        % Button pushed function: RunAnimationButton
        function RunAnimationButtonPushed(app, event)
            if app.RunAnimationButton.BackgroundColor == [1 0 0]
               %Wait for data to process before continuing
               pause(1)
            else
               %get input for msec average
               app.msec_avg = app.msectoAverageEditField.Value;
               %Pause for animations
               pause(2) 
               app.RunAnimationButton.BackgroundColor = [0 1 0];
               corrplot(app.msec_avg,app.direct_I,app.direct_Q, app.reflect_I, app.reflect_Q, app.Reflect_Direct_Ratio, app.Reflect_Composite)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputsTab = uitab(app.TabGroup);
            app.InputsTab.Title = 'Inputs';
            app.GridLayout = uigridlayout(app.InputsTab);
            app.PRNEditFieldLabel = uilabel(app.GridLayout);
            app.PRNEditFieldLabel.Text = 'PRN';
            app.PRNEditField = uieditfield(app.GridLayout, 'numeric');
            app.PRNEditField.Value = 18;
            app.SamplingFrequencyHzEditFieldLabel = uilabel(app.GridLayout);
            app.SamplingFrequencyHzEditFieldLabel.Text = 'Sampling Frequency (Hz)';
            app.SamplingFrequencyHzEditField = uieditfield(app.GridLayout, 'numeric');
            app.SamplingFrequencyHzEditField.Value = 4096000;
            app.IntermediateFrequencyHzEditFieldLabel = uilabel(app.GridLayout);
            app.IntermediateFrequencyHzEditFieldLabel.Text = 'Intermediate Frequency (Hz)';
            app.IntermediateFrequencyHzEditField = uieditfield(app.GridLayout, 'numeric');
            app.msectoprocessEditFieldLabel = uilabel(app.GridLayout);
            app.msectoprocessEditFieldLabel.Text = 'msec to process';
            app.msectoprocessEditField = uieditfield(app.GridLayout, 'numeric');
            app.msectoprocessEditField.Value = 1000;
            app.msectoAverageEditFieldLabel = uilabel(app.GridLayout);
            app.msectoAverageEditFieldLabel.Text = 'msec to Average';
            app.msectoAverageEditField = uieditfield(app.GridLayout, 'numeric');
            app.msectoAverageEditField.Value = 1000;
            app.ProcessDataButton = uibutton(app.GridLayout, 'push');
            app.ProcessDataButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessDataButtonPushed, true);
            app.ProcessDataButton.Text = 'Process Data';
            app.RunAnimationButton = uibutton(app.GridLayout, 'push');
            app.RunAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnimationButtonPushed, true);
            app.RunAnimationButton.Text = 'Run Animation';
            app.AmplitudeDataTab = uitab(app.TabGroup);
            app.AmplitudeDataTab.Title = 'Amplitude Data';
            app.GridLayout4 = uigridlayout(app.AmplitudeDataTab);
            app.Correlation_results = uiaxes(app.GridLayout4);
            title(app.Correlation_results, 'Correlation Results')
            xlabel(app.Correlation_results, 'milliseconds')
            ylabel(app.Correlation_results, 'Amplitude')
            app.Prompt_I = uiaxes(app.GridLayout4);
            title(app.Prompt_I, 'Prompt I Channel')
            xlabel(app.Prompt_I, 'milliseconds')
            ylabel(app.Prompt_I, 'Amplitude')
            app.Prompt_Q = uiaxes(app.GridLayout4);
            title(app.Prompt_Q, 'Prompt Q Channel')
            xlabel(app.Prompt_Q, 'milliseconds')
            ylabel(app.Prompt_Q, 'Amplitude')
            app.FrequencyDataTab = uitab(app.TabGroup);
            app.FrequencyDataTab.Title = 'Frequency Data';
            app.GridLayout3 = uigridlayout(app.FrequencyDataTab);
            app.Tracked_Code_Freq = uiaxes(app.GridLayout3);
            title(app.Tracked_Code_Freq, 'Tracked Code Frequency')
            xlabel(app.Tracked_Code_Freq, 'milliseconds')
            ylabel(app.Tracked_Code_Freq, 'Hz')
            app.Tracked_Int_Freq = uiaxes(app.GridLayout3);
            title(app.Tracked_Int_Freq, 'Tracked Intermediate Frequency')
            xlabel(app.Tracked_Int_Freq, 'milliseconds')
            ylabel(app.Tracked_Int_Freq, 'Hz')
            app.AnimationTab = uitab(app.TabGroup);
            app.AnimationTab.Title = 'Animation';
            app.GridLayout2 = uigridlayout(app.AnimationTab);
            app.direct_I = uiaxes(app.GridLayout2);
            title(app.direct_I, 'direct (I)')
            xlabel(app.direct_I, '')
            ylabel(app.direct_I, '')
            app.direct_Q = uiaxes(app.GridLayout2);
            title(app.direct_Q, 'direct (Q)')
            xlabel(app.direct_Q, '')
            ylabel(app.direct_Q, '')
            app.reflect_I = uiaxes(app.GridLayout2);
            title(app.reflect_I, 'reflect (I)')
            xlabel(app.reflect_I, '')
            ylabel(app.reflect_I, '')
            app.reflect_Q = uiaxes(app.GridLayout2);
            title(app.reflect_Q, 'reflect (Q)')
            xlabel(app.reflect_Q, '')
            ylabel(app.reflect_Q, '')
            app.Reflect_Direct_Ratio = uiaxes(app.GridLayout2);
            title(app.Reflect_Direct_Ratio, 'Reflect/Direct Ratio')
            xlabel(app.Reflect_Direct_Ratio, '')
            ylabel(app.Reflect_Direct_Ratio, '')
            app.Reflect_Composite = uiaxes(app.GridLayout2);
            title(app.Reflect_Composite, 'Reflected Composite')
            xlabel(app.Reflect_Composite, 'time elapsed')
            ylabel(app.Reflect_Composite, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ShittyAPP2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end