classdef SimulateDoublePendulum < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout2                  matlab.ui.container.GridLayout
        TabGroup                     matlab.ui.container.TabGroup
        InputsTab                    matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        LengthofBob2metersEditFieldLabel  matlab.ui.control.Label
        LengthofBob2metersEditField  matlab.ui.control.NumericEditField
        LengthofBob1metersLabel      matlab.ui.control.Label
        LengthofBob1metersEditField  matlab.ui.control.NumericEditField
        InitialAngleofBob1radsEditFieldLabel  matlab.ui.control.Label
        InitialAngleofBob1radsEditField  matlab.ui.control.NumericEditField
        InitialOmegaofBob1radssEditFieldLabel  matlab.ui.control.Label
        InitialOmegaofBob1radssEditField  matlab.ui.control.NumericEditField
        InitialAngleofBob2radsEditFieldLabel  matlab.ui.control.Label
        InitialAngleofBob2radsEditField  matlab.ui.control.NumericEditField
        InitialOmegaofBob2radssEditFieldLabel  matlab.ui.control.Label
        InitialOmegaofBob2radssEditField  matlab.ui.control.NumericEditField
        MassofBob2kgEditFieldLabel   matlab.ui.control.Label
        MassofBob2kgEditField        matlab.ui.control.NumericEditField
        MassofBob1kgEditFieldLabel   matlab.ui.control.Label
        MassofBob1kgEditField        matlab.ui.control.NumericEditField
        PlotPendulumButton           matlab.ui.control.Button
        StartTimesEditFieldLabel     matlab.ui.control.Label
        StartTimesEditField          matlab.ui.control.NumericEditField
        EndTimesEditFieldLabel       matlab.ui.control.Label
        EndTimesEditField            matlab.ui.control.NumericEditField
        PlottingTab                  matlab.ui.container.Tab
        GridLayout3                  matlab.ui.container.GridLayout
        Position                     matlab.ui.control.UIAxes
        AngleONE                     matlab.ui.control.UIAxes
        AngleTWO                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotPendulumButton
        function PlotPendulumButtonPushed(app, event)
            %clear axes in case of multiple runs
            cla(app.Position);
            cla(app.AngleONE);
            cla(app.AngleTWO);
            
            app.PlotPendulumButton.BackgroundColor = [0.97,0.84,0.41];
            app.PlotPendulumButton.Text = "Plotting, please wait..."
%             app.PlotPendulumButton.BackgroundColor = [1 0 0];
            % Pause ?
            pause(0.5)
            
            % Take input data and set to variables
            theta1_0 = app.InitialAngleofBob1radsEditField.Value;
            v_theta1_0 = app.InitialOmegaofBob1radssEditField.Value;
            theta2_0 = app.InitialAngleofBob2radsEditField.Value;
            v_theta2_0 = app.InitialOmegaofBob2radssEditField.Value;
            m1 = app.MassofBob1kgEditField.Value;
            m2 = app.MassofBob2kgEditField.Value;
            l1 = app.LengthofBob1metersEditField.Value;
            l2 = app.LengthofBob2metersEditField.Value;
            tstart = app.StartTimesEditField.Value;
            tstop = app.EndTimesEditField.Value;
            
            %plot results
            plotresults([tstart tstop],theta1_0, v_theta1_0, theta2_0, v_theta2_0, m1, m2, l1, l2, app.Position, app.AngleONE, app.AngleTWO)
            

            app.PlotPendulumButton.BackgroundColor = [0 1 0];
            app.PlotPendulumButton.Text = "Done plotting, proceed to next tab!"
            %Pause to allow app to catch up
            pause(0.5) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout2 = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.InputsTab = uitab(app.TabGroup);
            app.InputsTab.Title = 'Inputs';
            app.GridLayout = uigridlayout(app.InputsTab);
            app.LengthofBob2metersEditFieldLabel = uilabel(app.GridLayout);
            app.LengthofBob2metersEditFieldLabel.Text = 'Length of Bob 2 (meters)';
            app.LengthofBob2metersEditField = uieditfield(app.GridLayout, 'numeric');
            app.LengthofBob2metersEditField.Value = 2;
            app.LengthofBob1metersLabel = uilabel(app.GridLayout);
            app.LengthofBob1metersLabel.Text = 'Length of Bob 1 (meters)';
            app.LengthofBob1metersEditField = uieditfield(app.GridLayout, 'numeric');
            app.LengthofBob1metersEditField.Value = 1;
            app.InitialAngleofBob1radsEditFieldLabel = uilabel(app.GridLayout);
            app.InitialAngleofBob1radsEditFieldLabel.Text = 'Initial Angle of Bob 1 (rads)';
            app.InitialAngleofBob1radsEditField = uieditfield(app.GridLayout, 'numeric');
            app.InitialAngleofBob1radsEditField.Value = 1.5;
            app.InitialOmegaofBob1radssEditFieldLabel = uilabel(app.GridLayout);
            app.InitialOmegaofBob1radssEditFieldLabel.Text = 'Initial Omega of Bob 1 (rads/s)';
            app.InitialOmegaofBob1radssEditField = uieditfield(app.GridLayout, 'numeric');
            app.InitialAngleofBob2radsEditFieldLabel = uilabel(app.GridLayout);
            app.InitialAngleofBob2radsEditFieldLabel.Text = 'Initial Angle of Bob 2 (rads)';
            app.InitialAngleofBob2radsEditField = uieditfield(app.GridLayout, 'numeric');
            app.InitialAngleofBob2radsEditField.Value = 3;
            app.InitialOmegaofBob2radssEditFieldLabel = uilabel(app.GridLayout);
            app.InitialOmegaofBob2radssEditFieldLabel.Text = 'Initial Omega of Bob 2 (rads/s)';
            app.InitialOmegaofBob2radssEditField = uieditfield(app.GridLayout, 'numeric');
            app.InitialOmegaofBob2radssEditField.Value = 5;
            app.MassofBob2kgEditFieldLabel = uilabel(app.GridLayout);
            app.MassofBob2kgEditFieldLabel.Text = 'Mass of Bob 2 (kg)';
            app.MassofBob2kgEditField = uieditfield(app.GridLayout, 'numeric');
            app.MassofBob2kgEditField.Value = 1;
            app.MassofBob1kgEditFieldLabel = uilabel(app.GridLayout);
            app.MassofBob1kgEditFieldLabel.Text = 'Mass of Bob 1 (kg)';
            app.MassofBob1kgEditField = uieditfield(app.GridLayout, 'numeric');
            app.MassofBob1kgEditField.Value = 2;
            app.PlotPendulumButton = uibutton(app.GridLayout, 'push');
            app.PlotPendulumButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPendulumButtonPushed, true);
            app.PlotPendulumButton.Text = {'Plot Pendulum'; ''};
            app.StartTimesEditFieldLabel = uilabel(app.GridLayout);
            app.StartTimesEditFieldLabel.Text = 'Start Time (s)';
            app.StartTimesEditField = uieditfield(app.GridLayout, 'numeric');
            app.EndTimesEditFieldLabel = uilabel(app.GridLayout);
            app.EndTimesEditFieldLabel.Text = 'End Time (s)';
            app.EndTimesEditField = uieditfield(app.GridLayout, 'numeric');
            app.EndTimesEditField.Value = 10;
            app.PlottingTab = uitab(app.TabGroup);
            app.PlottingTab.Title = 'Plotting';
            app.GridLayout3 = uigridlayout(app.PlottingTab);
            app.Position = uiaxes(app.GridLayout3);
            title(app.Position, 'Pendulum Position')
            xlabel(app.Position, 'X [m]')
            ylabel(app.Position, 'Y [m]')
            app.AngleONE = uiaxes(app.GridLayout3);
            title(app.AngleONE, 'Bob 1 Angle vs Time')
            xlabel(app.AngleONE, 'time [s]')
            ylabel(app.AngleONE, 'Angle [radians]')
            app.AngleTWO = uiaxes(app.GridLayout3);
            title(app.AngleTWO, 'Bob 2 Angle vs Time')
            xlabel(app.AngleTWO, 'time [s]')
            ylabel(app.AngleTWO, 'Angle [radians]')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimulateDoublePendulum

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end